classdef consultas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConsultasUIFigure           matlab.ui.Figure
        ConsultadepacientesypruebasLabel  matlab.ui.control.Label
        PacienteEditFieldLabel      matlab.ui.control.Label
        PacienteEditField           matlab.ui.control.EditField
        SexoEditFieldLabel          matlab.ui.control.Label
        SexoEditField               matlab.ui.control.EditField
        FechadeNacimientoEditFieldLabel  matlab.ui.control.Label
        FechadeNacimientoEditField  matlab.ui.control.EditField
        personalesLabel             matlab.ui.control.Label
        patolgicosLabel             matlab.ui.control.Label
        deLabel                     matlab.ui.control.Label
        preescripcinLabel           matlab.ui.control.Label
        AntecedentesTextAreaLabel   matlab.ui.control.Label
        AntecedentesTextArea        matlab.ui.control.TextArea
        DiagnsticoTextAreaLabel     matlab.ui.control.Label
        DiagnsticoTextArea          matlab.ui.control.TextArea
        CondicinEditFieldLabel      matlab.ui.control.Label
        CondicinEditField           matlab.ui.control.EditField
        UITable                     matlab.ui.control.Table
        AgregarnuevoarchivoPanel    matlab.ui.container.Panel
        FechaDatePickerLabel        matlab.ui.control.Label
        FechaDatePicker             matlab.ui.control.DatePicker
        SeleccionarButton           matlab.ui.control.Button
        GuardarButton               matlab.ui.control.Button
        LeerButton                  matlab.ui.control.Button
        NuevaconsultaButton         matlab.ui.control.Button
        RegresarButton              matlab.ui.control.Button
        ActualizarButton            matlab.ui.control.Button
        HoraEditFieldLabel          matlab.ui.control.Label
        HoraEditField               matlab.ui.control.EditField
        EditFieldLabel              matlab.ui.control.Label
        EditField                   matlab.ui.control.EditField
    end

    
    properties (Access = private)
        CallingApp 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
        end

        % Button pushed function: RegresarButton
        function RegresarButtonPushed(app, event)
            app.CallingApp.AgregarnuevopacienteButton.Enable = 'on';
            app.CallingApp.ConsultarButton.Enable = 'on';
            app.CallingApp.RealizarprocesamientoButton.Enable = 'on';
            delete(app)
        end

        % Close request function: ConsultasUIFigure
        function ConsultasUIFigureCloseRequest(app, event)
            app.CallingApp.AgregarnuevopacienteButton.Enable = 'on';
            app.CallingApp.ConsultarButton.Enable = 'on';
            app.CallingApp.RealizarprocesamientoButton.Enable = 'on';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConsultasUIFigure = uifigure('Visible', 'off');
            app.ConsultasUIFigure.Name = 'Consultas';
            app.ConsultasUIFigure.CloseRequestFcn = createCallbackFcn(app, @ConsultasUIFigureCloseRequest, true);
            app.ConsultadepacientesypruebasLabel = uilabel(app.ConsultasUIFigure);
            app.ConsultadepacientesypruebasLabel.Text = 'Consulta de pacientes y pruebas';
            app.PacienteEditFieldLabel = uilabel(app.ConsultasUIFigure);
            app.PacienteEditFieldLabel.Text = 'Paciente';
            app.PacienteEditField = uieditfield(app.ConsultasUIFigure, 'text');
            app.SexoEditFieldLabel = uilabel(app.ConsultasUIFigure);
            app.SexoEditFieldLabel.Text = 'Sexo';
            app.SexoEditField = uieditfield(app.ConsultasUIFigure, 'text');
            app.FechadeNacimientoEditFieldLabel = uilabel(app.ConsultasUIFigure);
            app.FechadeNacimientoEditFieldLabel.Text = 'Fecha de Nacimiento';
            app.FechadeNacimientoEditField = uieditfield(app.ConsultasUIFigure, 'text');
            app.personalesLabel = uilabel(app.ConsultasUIFigure);
            app.personalesLabel.Text = 'personales';
            app.patolgicosLabel = uilabel(app.ConsultasUIFigure);
            app.patolgicosLabel.Text = 'patológicos';
            app.deLabel = uilabel(app.ConsultasUIFigure);
            app.deLabel.Text = 'de';
            app.preescripcinLabel = uilabel(app.ConsultasUIFigure);
            app.preescripcinLabel.Text = 'preescripción';
            app.AntecedentesTextAreaLabel = uilabel(app.ConsultasUIFigure);
            app.AntecedentesTextAreaLabel.Text = 'Antecedentes';
            app.AntecedentesTextArea = uitextarea(app.ConsultasUIFigure);
            app.DiagnsticoTextAreaLabel = uilabel(app.ConsultasUIFigure);
            app.DiagnsticoTextAreaLabel.Text = 'Diagnóstico';
            app.DiagnsticoTextArea = uitextarea(app.ConsultasUIFigure);
            app.CondicinEditFieldLabel = uilabel(app.ConsultasUIFigure);
            app.CondicinEditFieldLabel.Text = 'Condición';
            app.CondicinEditField = uieditfield(app.ConsultasUIFigure, 'text');
            app.UITable = uitable(app.ConsultasUIFigure);
            app.UITable.ColumnName = {'Prueba'; 'Fecha'; 'Hora'; 'Duración'};
            app.UITable.RowName = {};
            app.AgregarnuevoarchivoPanel = uipanel(app.ConsultasUIFigure);
            app.AgregarnuevoarchivoPanel.Title = 'Agregar nuevo archivo';
            app.HoraEditFieldLabel = uilabel(app.AgregarnuevoarchivoPanel);
            app.HoraEditFieldLabel.Text = 'Hora';
            app.HoraEditField = uieditfield(app.AgregarnuevoarchivoPanel, 'text');
            app.EditFieldLabel = uilabel(app.AgregarnuevoarchivoPanel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.AgregarnuevoarchivoPanel, 'text');
            app.FechaDatePickerLabel = uilabel(app.AgregarnuevoarchivoPanel);
            app.FechaDatePickerLabel.Text = 'Fecha';
            app.FechaDatePicker = uidatepicker(app.AgregarnuevoarchivoPanel);
            app.FechaDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.SeleccionarButton = uibutton(app.AgregarnuevoarchivoPanel, 'push');
            app.SeleccionarButton.Text = 'Seleccionar';
            app.GuardarButton = uibutton(app.AgregarnuevoarchivoPanel, 'push');
            app.GuardarButton.Text = 'Guardar';
            app.LeerButton = uibutton(app.ConsultasUIFigure, 'push');
            app.LeerButton.Text = 'Leer';
            app.NuevaconsultaButton = uibutton(app.ConsultasUIFigure, 'push');
            app.NuevaconsultaButton.Text = 'Nueva consulta';
            app.RegresarButton = uibutton(app.ConsultasUIFigure, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @RegresarButtonPushed, true);
            app.RegresarButton.Text = 'Regresar';
            app.ActualizarButton = uibutton(app.ConsultasUIFigure, 'push');
            app.ActualizarButton.Text = 'Actualizar';
            % Show the figure after all components are created
            app.ConsultasUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = consultas(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConsultasUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConsultasUIFigure)
        end
    end
end